classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageUIFigure                matlab.ui.Figure
        TimeSliderLabel              matlab.ui.control.Label
        TimeSlider                   matlab.ui.control.Slider
        RmmEditFieldLabel            matlab.ui.control.Label
        RmmEditField                 matlab.ui.control.NumericEditField
        ZaznaczROIButton             matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        Load2DimagefromscriptButton  matlab.ui.control.Button
    end

    properties (Access = public)
        SegImage Image % Image that was selected by user
    end
    
    methods (Access = private)
        
        function displayImage(app,t,z)
            app.UIAxes.XLim = [0 app.SegImage.dim(2)];
            app.UIAxes.YLim = [0 app.SegImage.dim(1)];
            imagesc(abs(squeeze(app.SegImage.voxels(t,z,:,:))),'Parent',app.UIAxes);
            hold(app.UIAxes);
            plot(app.UIAxes,app.SegImage.imageOrigin(2),app.SegImage.imageOrigin(1),'r+', 'MarkerSize', 10);
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.Visible = 'off';
            app.UIAxes.Colormap = gray(256);
            app.SegImage = Image(1,1,1,1);      
        end

        % Button pushed function: Load2DimagefromscriptButton
        function Load2DimagefromscriptButtonPushed(app, event)
            app.SegImage = read2DImageFromScript(app.SegImage);
            displayImage(app,1,1);
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)
            t = event.Value;
            t = round(t);
            if( t<1 )
                t=1;
            end
            displayImage(app,t,1);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageUIFigure = uifigure;
            app.ImageUIFigure.Name = 'Image';
            app.TimeSliderLabel = uilabel(app.ImageUIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.ImageUIFigure);
            app.TimeSlider.Limits = [0 201];
            app.TimeSlider.MajorTicks = [0 20 40 60 80 100 120 140 160 180 201];
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
            app.RmmEditFieldLabel = uilabel(app.ImageUIFigure);
            app.RmmEditFieldLabel.Text = 'R [mm]';
            app.RmmEditField = uieditfield(app.ImageUIFigure, 'numeric');
            app.ZaznaczROIButton = uibutton(app.ImageUIFigure, 'push');
            app.ZaznaczROIButton.Text = 'Zaznacz ROI';
            app.UIAxes = uiaxes(app.ImageUIFigure);
            title(app.UIAxes, 'Image')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {'[ ]'};
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {'[ ]'};
            app.Load2DimagefromscriptButton = uibutton(app.ImageUIFigure, 'push');
            app.Load2DimagefromscriptButton.ButtonPushedFcn = createCallbackFcn(app, @Load2DimagefromscriptButtonPushed, true);
            app.Load2DimagefromscriptButton.Text = 'Load 2D image from script';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageUIFigure)
        end
    end
end